classdef app2_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        DataInputTab                   matlab.ui.container.Tab
        UpdateDataButton               matlab.ui.control.Button
        StartFileEditField_2Label      matlab.ui.control.Label
        StartFile                      matlab.ui.control.NumericEditField
        VsdmVEditField_2Label          matlab.ui.control.Label
        Vsd                            matlab.ui.control.NumericEditField
        BNThicknessnmEditField_2Label  matlab.ui.control.Label
        BNThickness                    matlab.ui.control.NumericEditField
        StartGateVoltageVEditField_2Label  matlab.ui.control.Label
        StartGateVoltage               matlab.ui.control.NumericEditField
        GateVoltageStepVEditField_2Label  matlab.ui.control.Label
        GateVoltageStep                matlab.ui.control.NumericEditField
        FinalGateVoltageVEditField_2Label  matlab.ui.control.Label
        FinalGateVoltage               matlab.ui.control.NumericEditField
        DeviceWidthMicronsEditField_2Label  matlab.ui.control.Label
        DeviceWidth                    matlab.ui.control.NumericEditField
        DeviceLengthMicronsEditField_2Label  matlab.ui.control.Label
        DeviceLength                   matlab.ui.control.NumericEditField
        MoirePeriodicitynmEditField_2Label  matlab.ui.control.Label
        MoirePeriodicity               matlab.ui.control.NumericEditField
        CheckBox                       matlab.ui.control.CheckBox
        LabelsTab                      matlab.ui.container.Tab
        XAxisDropDownLabel             matlab.ui.control.Label
        XAxisDropDown                  matlab.ui.control.DropDown
        YAxisDropDownLabel             matlab.ui.control.Label
        YAxisDropDown                  matlab.ui.control.DropDown
        ZAxisDropDownLabel             matlab.ui.control.Label
        ZAxisDropDown                  matlab.ui.control.DropDown
        ColormapDropDownLabel          matlab.ui.control.Label
        ColormapDropDown               matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        xhlimit                        matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        xllimit                        matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        yllimit                        matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        zhlimit                        matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        zllimit                        matlab.ui.control.NumericEditField
        logxbutton                     matlab.ui.control.CheckBox
        logybutton                     matlab.ui.control.CheckBox
        logzbutton                     matlab.ui.control.CheckBox
        Label_4                        matlab.ui.control.Label
        yhlimit                        matlab.ui.control.NumericEditField
        resetxlow                      matlab.ui.control.Button
        resetxhigh                     matlab.ui.control.Button
        resetylow                      matlab.ui.control.Button
        resetyhigh                     matlab.ui.control.Button
        resetzlow                      matlab.ui.control.Button
        resetzhigh                     matlab.ui.control.Button
        logzbutton_2                   matlab.ui.control.CheckBox
        holdx                          matlab.ui.control.StateButton
        holdy                          matlab.ui.control.StateButton
        holdz                          matlab.ui.control.StateButton
        Tab                            matlab.ui.container.Tab
        Switch                         matlab.ui.control.Switch
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        SaveFigureButton               matlab.ui.control.Button
        histogramchart                 matlab.ui.control.UIAxes
        FileMenu                       matlab.ui.container.Menu
        ChangeDirectoryMenu            matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
        minx; 
        maxx;
        miny;
        maxy;
        minz;
        maxz;
        limz;
        datae;
    end
    
    methods (Access = private)
        
        function [] = bmapplot(app)
            %data = load("mappingdata.mat");
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.histogramchart);
            
            % Determine Data
            x = app.XAxisDropDown.Value;
            y = app.YAxisDropDown.Value;
            z = app.ZAxisDropDown.Value;
            
            % Get Previous Information
            xplim = app.UIAxes.XLim;
            yplim = app.UIAxes.YLim;
            zplim = app.UIAxes.CLim;
            %map = imagesc(app.UIAxes,app.datae.(x),app.datae.(y),app.datae.(z));
            
            axInnerPos = app.UIAxes.InnerPosition; 
            map = pcolor(app.UIAxes,app.datae.(x),app.datae.(y),app.datae.(z));

            set(map,'EdgeColor','none');
            shading(app.UIAxes,'interp');
            colorbar(app.UIAxes, 'off');
            colorbar(app.UIAxes);
            
            value = app.ColormapDropDown.Value;
            colorscheme = othercolor(value,1024);
            flippedJetColorMap = (colorscheme);
            colormap(app.UIFigure,flippedJetColorMap);
           
            if app.holdx.Value == 1
                xlim(app.UIAxes, xplim);
            else
                 app.minx = round(min(min(app.datae.(x))),5); app.maxx = round(max(max(app.datae.(x))),5);
                 xlim(app.UIAxes, [app.minx app.maxx]);
                 
                 app.xhlimit.Value = app.maxx;
                 app.xllimit.Value = app.minx;
            end
            
            if app.holdy.Value == 1  
                ylim(app.UIAxes, yplim);
            else
                app.miny = round(min(min(app.datae.(y))),5); app.maxy = round(max(max(app.datae.(y))),5);
                ylim(app.UIAxes, [app.miny app.maxy]);
                
                app.yhlimit.Value = app.maxy;
                app.yllimit.Value = app.miny;                 
            end
            
            if app.holdz.Value == 1
                caxis(app.UIAxes, zplim);
            else
                app.minz = round(min(min(app.datae.(z))),5); app.maxz = round(max(max(app.datae.(z))),5);  
                caxis(app.UIAxes, [app.minz app.maxz]);
                
                app.zhlimit.Value = app.maxz;
                app.zllimit.Value = app.minz;
            end
           
            if strcmp(x,'phiophi') == 1
                app.minx = 0;
                app.maxx = 20;
            elseif strcmp(y,'phiophi') == 1
                 app.miny = 0;
                 app.maxy = 20;
            end
            
            app.resetxlow.Text = [app.XAxisDropDown.Value ' Low'];
            app.resetxhigh.Text = [app.XAxisDropDown.Value ' High'];
            app.resetylow.Text = [app.YAxisDropDown.Value ' Low'];
            app.resetyhigh.Text = [app.YAxisDropDown.Value ' High'];
            app.resetzlow.Text = [app.ZAxisDropDown.Value ' Low'];
            app.resetzhigh.Text = [app.ZAxisDropDown.Value ' High'];  
            
            app.UIAxes.XLabel.String = app.datae.xy.(app.XAxisDropDown.Value); 
            app.UIAxes.YLabel.String = app.datae.xy.(app.YAxisDropDown.Value);
            
            innerPosDiff = axInnerPos - app.UIAxes.InnerPosition;   %3) calculate difference between original and current inner ax pos.
            app.UIAxes.Position = app.UIAxes.Position + innerPosDiff; %4) change the 'position' so inner position matches original values
            
            a = histogram(app.histogramchart,app.datae.(z),1000);
            ylim(app.histogramchart, [0 max(a.Values)]);
            xlim(app.histogramchart, [app.minz app.maxz]);
            
        end
        
        function [] = waterfall(app)
            %data = load("mappingdata.mat");
            disableDefaultInteractivity(app.UIAxes);
            clf(app.UIAxes,'reset')
            % Determine
            x = app.XAxisDropDown.Value;
            y = app.YAxisDropDown.Value;
            z = app.ZAxisDropDown.Value;
            
            app.minx = round(min(min(app.datae.(x))),5); app.maxx = round(max(max(app.datae.(x))),5);
            app.miny = round(min(min(app.datae.(y))),5); app.maxy = round(max(max(app.datae.(y))),5);
            app.minz = round(min(min(app.datae.(z))),5); app.maxz = round(max(max(app.datae.(z))),5);  
            
            if strcmp(x,'phiophi') == 1
                app.minx = 0;
                app.maxx = 20;
            elseif strcmp(y,'phiophi') == 1
                 app.miny = 0;
                 app.maxy = 20;
            end
            
            %map = imagesc(app.UIAxes,app.datae.(x),app.datae.(y),app.datae.(z));
            clf(app.UIAxes,'reset')
            
            for j = 1:size(app.datae.(z),2)
                plot(app.UIAxes,app.datae.(x)((1:end),j),app.datae.(y)((1:end),j));
                j = j + 1;
                hold(app.UIAxes,'on')
            end
            hold(app.UIAxes,'off')      
            
                        set(map,'EdgeColor','none');
            shading(app.UIAxes,'interp');
            colorbar(app.UIAxes, 'off');
            colorbar(app.UIAxes);
            app.ColormapDropDown.Value = "Greys9";
            
            xlim(app.UIAxes, [app.minx app.maxx]);
            ylim(app.UIAxes, [app.miny app.maxy]);
            caxis(app.UIAxes, [app.minz app.maxz]);
              
            app.xhlimit.Value = app.maxx;
            app.xllimit.Value = app.minx;
            
            app.yhlimit.Value = app.maxy;
            app.yllimit.Value = app.miny;     
            
            app.zhlimit.Value = app.maxz;
            app.zllimit.Value = app.minz;
            
            app.resetxlow.Text = [app.XAxisDropDown.Value ' Low'];
            app.resetxhigh.Text = [app.XAxisDropDown.Value ' High'];
            app.resetylow.Text = [app.YAxisDropDown.Value ' Low'];
            app.resetyhigh.Text = [app.YAxisDropDown.Value ' High'];
            app.resetzlow.Text = [app.ZAxisDropDown.Value ' Low'];
            app.resetzhigh.Text = [app.ZAxisDropDown.Value ' High'];  
            
            app.UIAxes.XLabel.String = app.datae.xy.(app.XAxisDropDown.Value); 
            app.UIAxes.YLabel.String = app.datae.xy.(app.YAxisDropDown.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateDataButton
        function UpdateDataButtonPushed(app, event)
            start_file = (app.StartFile.Value);
            start_gate = (app.StartGateVoltage.Value);
            final_gate = (app.FinalGateVoltage.Value);
            delvg = (app.GateVoltageStep.Value);
            
            app.datae = bgmapping(start_file,start_gate,delvg,final_gate,...
                'vsd',app.Vsd.Value./1000,'bnthickness',app.BNThickness,'width',...
                app.DeviceWidth,'length',app.DeviceLength,'moire',app.MoirePeriodicity)
            app.XAxisDropDown.Items = fieldnames(app.datae.xy);
            app.YAxisDropDown.Items = fieldnames(app.datae.xy);
            app.ZAxisDropDown.Items = fieldnames(app.datae.z);
            
            app.UIAxes.Title.String = app.datae.title;
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
           
        end

        % Size changed function: LabelsTab
        function LabelsTabSizeChanged(app, event)
            position = app.LabelsTab.Position;
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            if strcmp(app.Switch.Value,'2D') == 1 
                app.bmapplot();
            else 
                app.waterfall();
            end
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            if strcmp(app.Switch.Value,'2D') == 1 
                app.bmapplot();
            else 
                app.waterfall();
            end      
        end

        % Value changed function: ZAxisDropDown
        function ZAxisDropDownValueChanged(app, event)
            if strcmp(app.Switch.Value,'2D') == 1 
                app.bmapplot();
            else 
                app.waterfall();
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            colorscheme = othercolor(value,1024);
            flippedJetColorMap = (colorscheme);
            colormap(app.UIFigure,flippedJetColorMap);
        end

        % Value changed function: xhlimit
        function xhlimitValueChanged(app, event)
            % Change X High Limits
            xlimhigh = app.xhlimit.Value;
            lim = app.UIAxes.XLim;
            xlim(app.UIAxes, [lim(1) xlimhigh])   
        end

        % Value changed function: xllimit
        function xllimitValueChanged(app, event)
            xlimlow = app.xllimit.Value;
            lim = app.UIAxes.XLim;
            xlim(app.UIAxes, [xlimlow lim(2)])   
        end

        % Value changed function: yllimit
        function yllimitValueChanged(app, event)
            ylimlow = app.yllimit.Value;
            lim = app.UIAxes.YLim;
            ylim(app.UIAxes, [ylimlow lim(2)])   
        end

        % Value changed function: zhlimit
        function zhlimitValueChanged(app, event)
            zlimhigh = app.zhlimit.Value;
            lim = caxis(app.UIAxes);
            caxis(app.UIAxes, [lim(1) zlimhigh]) 
        end

        % Value changed function: zllimit
        function zllimitValueChanged(app, event)
            zlimlow = app.zllimit.Value;
            lim = caxis(app.UIAxes);
            caxis(app.UIAxes, [zlimlow lim(2)]) 
        end

        % Value changed function: logxbutton
        function logxbuttonValueChanged(app, event)
            checkbox = app.logxbutton.Value;
            if checkbox == 1
                set(app.UIAxes, 'XScale', 'log')
            else
                set(app.UIAxes, 'XScale', 'linear')
            end
        end

        % Value changed function: logybutton
        function logybuttonValueChanged(app, event)
            checkbox = app.logybutton.Value;
            if checkbox == 1
                set(app.UIAxes, 'YScale', 'log')
            else
                set(app.UIAxes, 'YScale', 'linear')
            end
        end

        % Value changed function: logzbutton
        function logzbuttonValueChanged(app, event)
            checkbox = app.logzbutton.Value;
            if checkbox == 1
                app.limz = caxis(app.UIAxes); 
                caxis(app.UIAxes, [log(app.limz(1)) log(app.limz(2))])
            else
                caxis(app.UIAxes, [app.limz(1) app.limz(2)])
            end
        end

        % Value changed function: yhlimit
        function yhlimitValueChanged(app, event)
            ylimhigh = app.yhlimit.Value;
            lim = app.UIAxes.YLim;
            ylim(app.UIAxes, [lim(1) ylimhigh])   
        end

        % Callback function
        function autoxbuttonValueChanged(app, event)
            autox = app.autoxbutton.Value;
            if autox == 1
               xlim(app.UIAxes,[app.minx app.maxx])
               app.xllimit.Value = app.minx;
               app.xhlimit.Value = app.maxx;
               app.autoxbutton.Value = 0;
            end 
        end

        % Callback function
        function autoybuttonValueChanged(app, event)
            autoy = app.autoybutton.Value;
            if autoy == 1
               ylim(app.UIAxes,[app.miny app.maxy])
               app.yllimit.Value = app.miny;
               app.yhlimit.Value = app.maxy;
               app.autoybutton.Value = 0;
            end 
        end

        % Callback function
        function autozbuttonValueChanged(app, event)
            autoz = app.autozbutton.Value;
            if autoz == 1
               caxis(app.UIAxes,[app.minz app.maxz])
               app.zllimit.Value = app.minz;
               app.zhlimit.Value = app.minz;
               app.autozbutton.Value = 0;
            end 
        end

        % Button pushed function: resetxlow
        function resetxlowPushed(app, event)
           xlim(app.UIAxes,[app.minx app.xhlimit.Value])
           app.xllimit.Value = app.minx;
        end

        % Button pushed function: resetxhigh
        function resetxhighButtonPushed(app, event)
           xlim(app.UIAxes,[app.xllimit.Value app.maxx])
           app.xhlimit.Value = app.maxx;            
        end

        % Button pushed function: resetylow
        function resetylowButtonPushed(app, event)
           ylim(app.UIAxes,[app.miny app.yhlimit.Value])
           app.yllimit.Value = app.miny;            
        end

        % Button pushed function: resetyhigh
        function resetyhighButtonPushed(app, event)
           ylim(app.UIAxes,[app.yllimit.Value app.maxy])
           app.yhlimit.Value = app.maxy;             
        end

        % Button pushed function: resetzlow
        function resetzlowButtonPushed(app, event)
           caxis(app.UIAxes,[app.minz app.zhlimit.Value])
           app.zllimit.Value = app.minz;
        end

        % Button pushed function: resetzhigh
        function resetzhighButtonPushed(app, event)
           caxis(app.UIAxes,[app.zllimit.Value app.maxz])
           app.zhlimit.Value = app.maxz;          
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
        if strcmp(app.Switch.Value,'Waterfall') == 1
            app.XAxisDropDown.Items = fieldnames(app.datae.xy);
            app.YAxisDropDown.Items = fieldnames(app.datae.z);
            app.ZAxisDropDown.Items = fieldnames(app.datae.xy);
        end
        
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            h = copyUIAxes(app.UIAxes);
            colorbar();
        end

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData
            
        end

        % Menu selected function: ChangeDirectoryMenu
        function ChangeDirectoryMenuSelected(app, event)
            newdir = uigetdir; 
            cd(newdir);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {493, 493};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {297, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DataInputTab = uitab(app.TabGroup);
            app.DataInputTab.Title = 'Data Input';
            app.UpdateDataButton = uibutton(app.DataInputTab, 'push');
            app.UpdateDataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataButtonPushed, true);
            app.UpdateDataButton.Text = 'Update Data';
            app.StartFileEditField_2Label = uilabel(app.DataInputTab);
            app.StartFileEditField_2Label.Text = 'Start File';
            app.StartFile = uieditfield(app.DataInputTab, 'numeric');
            app.StartFile.Value = 1223;
            app.VsdmVEditField_2Label = uilabel(app.DataInputTab);
            app.VsdmVEditField_2Label.Text = 'Vsd (mV)';
            app.Vsd = uieditfield(app.DataInputTab, 'numeric');
            app.Vsd.Value = 0.005;
            app.BNThicknessnmEditField_2Label = uilabel(app.DataInputTab);
            app.BNThicknessnmEditField_2Label.Text = 'BN Thickness (nm)';
            app.BNThickness = uieditfield(app.DataInputTab, 'numeric');
            app.BNThickness.Value = 90;
            app.StartGateVoltageVEditField_2Label = uilabel(app.DataInputTab);
            app.StartGateVoltageVEditField_2Label.Text = 'Start Gate Voltage (V)';
            app.StartGateVoltage = uieditfield(app.DataInputTab, 'numeric');
            app.StartGateVoltage.Value = -90;
            app.GateVoltageStepVEditField_2Label = uilabel(app.DataInputTab);
            app.GateVoltageStepVEditField_2Label.Text = 'Gate Voltage Step (V)';
            app.GateVoltageStep = uieditfield(app.DataInputTab, 'numeric');
            app.GateVoltageStep.Value = 5;
            app.FinalGateVoltageVEditField_2Label = uilabel(app.DataInputTab);
            app.FinalGateVoltageVEditField_2Label.Text = 'Final Gate Voltage (V)';
            app.FinalGateVoltage = uieditfield(app.DataInputTab, 'numeric');
            app.FinalGateVoltage.Value = 80;
            app.DeviceWidthMicronsEditField_2Label = uilabel(app.DataInputTab);
            app.DeviceWidthMicronsEditField_2Label.Text = 'Device Width (Microns)';
            app.DeviceWidth = uieditfield(app.DataInputTab, 'numeric');
            app.DeviceWidth.Value = 5;
            app.DeviceLengthMicronsEditField_2Label = uilabel(app.DataInputTab);
            app.DeviceLengthMicronsEditField_2Label.Text = 'Device Length (Microns)';
            app.DeviceLength = uieditfield(app.DataInputTab, 'numeric');
            app.DeviceLength.Value = 5;
            app.MoirePeriodicitynmEditField_2Label = uilabel(app.DataInputTab);
            app.MoirePeriodicitynmEditField_2Label.Text = 'Moire Periodicity (nm)';
            app.MoirePeriodicity = uieditfield(app.DataInputTab, 'numeric');
            app.MoirePeriodicity.Value = 14;
            app.CheckBox = uicheckbox(app.DataInputTab);
            app.CheckBox.Text = '';
            app.LabelsTab = uitab(app.TabGroup);
            app.LabelsTab.SizeChangedFcn = createCallbackFcn(app, @LabelsTabSizeChanged, true);
            app.LabelsTab.Title = 'Labels';
            app.XAxisDropDownLabel = uilabel(app.LabelsTab);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.LabelsTab);
            app.XAxisDropDown.Items = {'Option 1', 'Option 5', 'Option 2', 'Option 3', 'Option 4'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.YAxisDropDownLabel = uilabel(app.LabelsTab);
            app.YAxisDropDownLabel.Text = 'Y Axis';
            app.YAxisDropDown = uidropdown(app.LabelsTab);
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.ZAxisDropDownLabel = uilabel(app.LabelsTab);
            app.ZAxisDropDownLabel.Text = 'Z Axis';
            app.ZAxisDropDown = uidropdown(app.LabelsTab);
            app.ZAxisDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', ''};
            app.ZAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ZAxisDropDownValueChanged, true);
            app.ColormapDropDownLabel = uilabel(app.LabelsTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.LabelsTab);
            app.ColormapDropDown.Items = {'Accent3', 'Accent4', 'Accent5', 'Accent6', 'Accent7', 'Accent8', 'Blues3', 'Blues4', 'Blues5', 'Blues6', 'Blues7', 'Blues8', 'Blues9', 'BrBG10', 'BrBG11', 'BrBG3', 'BrBG4', 'BrBG5', 'BrBG6', 'BrBG7', 'BrBG8', 'BrBG9', 'BuGn3', 'BuGn4', 'BuGn5', 'BuGn6', 'BuGn7', 'BuGn8', 'BuGn9', 'BuPu3', 'BuPu4', 'BuPu5', 'BuPu6', 'BuPu7', 'BuPu8', 'BuPu9', 'Dark23', 'Dark24', 'Dark25', 'Dark26', 'Dark27', 'Dark28', 'GnBu3', 'GnBu4', 'GnBu5', 'GnBu6', 'GnBu7', 'GnBu8', 'GnBu9', 'Greens3', 'Greens4', 'Greens5', 'Greens6', 'Greens7', 'Greens8', 'Greens9', 'Greys3', 'Greys4', 'Greys5', 'Greys6', 'Greys7', 'Greys8', 'Greys9', 'OrRd3', 'OrRd4', 'OrRd5', 'OrRd6', 'OrRd7', 'OrRd8', 'OrRd9', 'Oranges3', 'Oranges4', 'Oranges5', 'Oranges6', 'Oranges7', 'Oranges8', 'Oranges9', 'PRGn10', 'PRGn11', 'PRGn3', 'PRGn4', 'PRGn5', 'PRGn6', 'PRGn7', 'PRGn8', 'PRGn9', 'Paired10', 'Paired11', 'Paired12', 'Paired3', 'Paired4', 'Paired5', 'Paired6', 'Paired7', 'Paired8', 'Paired9', 'Pastel13', 'Pastel14', 'Pastel15', 'Pastel16', 'Pastel17', 'Pastel18', 'Pastel19', 'Pastel23', 'Pastel24', 'Pastel25', 'Pastel26', 'Pastel27', 'Pastel28', 'PiYG10', 'PiYG11', 'PiYG3', 'PiYG4', 'PiYG5', 'PiYG6', 'PiYG7', 'PiYG8', 'PiYG9', 'PuBu3', 'PuBu4', 'PuBu5', 'PuBu6', 'PuBu7', 'PuBu8', 'PuBu9', 'PuBuGn3', 'PuBuGn4', 'PuBuGn5', 'PuBuGn6', 'PuBuGn7', 'PuBuGn8', 'PuBuGn9', 'PuOr10', 'PuOr11', 'PuOr3', 'PuOr4', 'PuOr5', 'PuOr6', 'PuOr7', 'PuOr8', 'PuOr9', 'PuRd3', 'PuRd4', 'PuRd5', 'PuRd6', 'PuRd7', 'PuRd8', 'PuRd9', 'Purples3', 'Purples4', 'Purples5', 'Purples6', 'Purples7', 'Purples8', 'Purples9', 'RdBu10', 'RdBu11', 'RdBu3', 'RdBu4', 'RdBu5', 'RdBu6', 'RdBu7', 'RdBu8', 'RdBu9', 'RdGy10', 'RdGy11', 'RdGy3', 'RdGy4', 'RdGy5', 'RdGy6', 'RdGy7', 'RdGy8', 'RdGy9', 'RdPu3', 'RdPu4', 'RdPu5', 'RdPu6', 'RdPu7', 'RdPu8', 'RdPu9', 'RdYlBu10', 'RdYlBu11', 'RdYlBu3', 'RdYlBu4', 'RdYlBu5', 'RdYlBu6', 'RdYlBu7', 'RdYlBu8', 'RdYlBu9', 'RdYlGn10', 'RdYlGn11', 'RdYlGn3', 'RdYlGn4', 'RdYlGn5', 'RdYlGn6', 'RdYlGn7', 'RdYlGn8', 'RdYlGn9', 'Reds3', 'Reds4', 'Reds5', 'Reds6', 'Reds7', 'Reds8', 'Reds9', 'Set13', 'Set14', 'Set15', 'Set16', 'Set17', 'Set18', 'Set19', 'Set23', 'Set24', 'Set25', 'Set26', 'Set27', 'Set28', 'Set310', 'Set311', 'Set312', 'Set33', 'Set34', 'Set35', 'Set36', 'Set37', 'Set38', 'Set39', 'Spectral10', 'Spectral11', 'Spectral3', 'Spectral4', 'Spectral5', 'Spectral6', 'Spectral7', 'Spectral8', 'Spectral9', 'YlGn3', 'YlGn4', 'YlGn5', 'YlGn6', 'YlGn7', 'YlGn8', 'YlGn9', 'YlGnBu3', 'YlGnBu4', 'YlGnBu5', 'YlGnBu6', 'YlGnBu7', 'YlGnBu8', 'YlGnBu9', 'YlOrBr3', 'YlOrBr4', 'YlOrBr5', 'YlOrBr6', 'YlOrBr7', 'YlOrBr8', 'YlOrBr9', 'YlOrRd3', 'YlOrRd4', 'YlOrRd5', 'YlOrRd6', 'YlOrRd7', 'YlOrRd8', 'YlOrRd9'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Greys9';
            app.Label_2 = uilabel(app.LabelsTab);
            app.Label_2.Text = {''; ''};
            app.xhlimit = uieditfield(app.LabelsTab, 'numeric');
            app.xhlimit.ValueChangedFcn = createCallbackFcn(app, @xhlimitValueChanged, true);
            app.Label = uilabel(app.LabelsTab);
            app.Label.Text = {''; ''};
            app.xllimit = uieditfield(app.LabelsTab, 'numeric');
            app.xllimit.ValueChangedFcn = createCallbackFcn(app, @xllimitValueChanged, true);
            app.Label_3 = uilabel(app.LabelsTab);
            app.Label_3.Text = {''; ''};
            app.yllimit = uieditfield(app.LabelsTab, 'numeric');
            app.yllimit.ValueChangedFcn = createCallbackFcn(app, @yllimitValueChanged, true);
            app.Label_6 = uilabel(app.LabelsTab);
            app.Label_6.Text = {''; ''};
            app.zhlimit = uieditfield(app.LabelsTab, 'numeric');
            app.zhlimit.ValueChangedFcn = createCallbackFcn(app, @zhlimitValueChanged, true);
            app.Label_5 = uilabel(app.LabelsTab);
            app.Label_5.Text = {''; ''};
            app.zllimit = uieditfield(app.LabelsTab, 'numeric');
            app.zllimit.ValueChangedFcn = createCallbackFcn(app, @zllimitValueChanged, true);
            app.logxbutton = uicheckbox(app.LabelsTab);
            app.logxbutton.ValueChangedFcn = createCallbackFcn(app, @logxbuttonValueChanged, true);
            app.logxbutton.Text = 'Log';
            app.logybutton = uicheckbox(app.LabelsTab);
            app.logybutton.ValueChangedFcn = createCallbackFcn(app, @logybuttonValueChanged, true);
            app.logybutton.Text = 'Log';
            app.logzbutton = uicheckbox(app.LabelsTab);
            app.logzbutton.ValueChangedFcn = createCallbackFcn(app, @logzbuttonValueChanged, true);
            app.logzbutton.Text = 'Log';
            app.Label_4 = uilabel(app.LabelsTab);
            app.Label_4.Text = {''; ''};
            app.yhlimit = uieditfield(app.LabelsTab, 'numeric');
            app.yhlimit.ValueChangedFcn = createCallbackFcn(app, @yhlimitValueChanged, true);
            app.resetxlow = uibutton(app.LabelsTab, 'push');
            app.resetxlow.ButtonPushedFcn = createCallbackFcn(app, @resetxlowPushed, true);
            app.resetxlow.Text = 'X Low';
            app.resetxhigh = uibutton(app.LabelsTab, 'push');
            app.resetxhigh.ButtonPushedFcn = createCallbackFcn(app, @resetxhighButtonPushed, true);
            app.resetxhigh.Text = 'X High';
            app.resetylow = uibutton(app.LabelsTab, 'push');
            app.resetylow.ButtonPushedFcn = createCallbackFcn(app, @resetylowButtonPushed, true);
            app.resetylow.Text = 'Y Low';
            app.resetyhigh = uibutton(app.LabelsTab, 'push');
            app.resetyhigh.ButtonPushedFcn = createCallbackFcn(app, @resetyhighButtonPushed, true);
            app.resetyhigh.Text = 'Y High';
            app.resetzlow = uibutton(app.LabelsTab, 'push');
            app.resetzlow.ButtonPushedFcn = createCallbackFcn(app, @resetzlowButtonPushed, true);
            app.resetzlow.Text = 'Z Low';
            app.resetzhigh = uibutton(app.LabelsTab, 'push');
            app.resetzhigh.ButtonPushedFcn = createCallbackFcn(app, @resetzhighButtonPushed, true);
            app.resetzhigh.Text = 'Z High';
            app.logzbutton_2 = uicheckbox(app.LabelsTab);
            app.logzbutton_2.Text = 'Flip';
            app.holdx = uibutton(app.LabelsTab, 'state');
            app.holdx.Text = 'Hold';
            app.holdy = uibutton(app.LabelsTab, 'state');
            app.holdy.Text = 'Hold';
            app.holdz = uibutton(app.LabelsTab, 'state');
            app.holdz.Text = 'Hold';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Switch = uiswitch(app.Tab, 'slider');
            app.Switch.Items = {'2D', 'Waterfall'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '2D';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SaveFigureButton = uibutton(app.RightPanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.histogramchart = uiaxes(app.RightPanel);
            title(app.histogramchart, '')
            xlabel(app.histogramchart, '')
            ylabel(app.histogramchart, '')
            app.histogramchart.XAxisLocation = 'top';
            app.histogramchart.YTick = [0 1];
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ChangeDirectoryMenu = uimenu(app.FileMenu);
            app.ChangeDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeDirectoryMenuSelected, true);
            app.ChangeDirectoryMenu.Text = 'Change Directory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end